classdef KaciraLabCropAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KaciraLabCropAnalysisUIFigure   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisSettingsTab             matlab.ui.container.Tab
        HueThresholdLowSliderLabel      matlab.ui.control.Label
        HueThresholdLowSlider           matlab.ui.control.Slider
        HueThresholdHighSliderLabel     matlab.ui.control.Label
        HueThresholdHighSlider          matlab.ui.control.Slider
        SaturationThresholdLowSliderLabel  matlab.ui.control.Label
        SaturationThresholdLowSlider    matlab.ui.control.Slider
        SaturationThresholdHighSliderLabel  matlab.ui.control.Label
        SaturationThresholdHighSlider   matlab.ui.control.Slider
        LuminanceThresholdLowSliderLabel  matlab.ui.control.Label
        LuminanceThresholdLowSlider     matlab.ui.control.Slider
        LuminanceThresholdHighSliderLabel  matlab.ui.control.Label
        LuminanceThresholdHighSlider    matlab.ui.control.Slider
        SetHueThresholdsLabel           matlab.ui.control.Label
        SetSaturationThresholdsLabel    matlab.ui.control.Label
        SetLuminanceThresholdsLabel     matlab.ui.control.Label
        ResetToDefaultHueValuesButton   matlab.ui.control.Button
        ResetToDefaultSaturationValues  matlab.ui.control.Button
        ResetToDefaultLuminanceValues   matlab.ui.control.Button
        SetHueThresholdLowEditFieldLabel  matlab.ui.control.Label
        SetHueThresholdLowEditField     matlab.ui.control.NumericEditField
        SetHueThresholdHighEditFieldLabel  matlab.ui.control.Label
        SetHueThresholdHighEditField    matlab.ui.control.NumericEditField
        SetSaturationThresholdLowEditFieldLabel  matlab.ui.control.Label
        SetSaturationThresholdLowEditField  matlab.ui.control.NumericEditField
        SetSaturationThresholdHighEditFieldLabel  matlab.ui.control.Label
        SetSaturationThresholdHighEditField  matlab.ui.control.NumericEditField
        SetLuminanceThresholdLowEditFieldLabel  matlab.ui.control.Label
        SetLuminanceThresholdLowEditField  matlab.ui.control.NumericEditField
        SetLuminanceThresholdHighEditFieldLabel  matlab.ui.control.Label
        SetLuminanceThresholdHighEditField  matlab.ui.control.NumericEditField
        BlobAnalysisSettingsLabel       matlab.ui.control.Label
        ResetToDefaultBlobAnalysisButton  matlab.ui.control.Button
        BlobClumpingDistanceEditFieldLabel  matlab.ui.control.Label
        BlobClumpingDistanceEditField   matlab.ui.control.NumericEditField
        MaximumAreaToFillPixelsEditFieldLabel  matlab.ui.control.Label
        MaximumAreaToFillPixelsEditField  matlab.ui.control.NumericEditField
        MinimumSizeForBlobDetectionPixelsEditFieldLabel  matlab.ui.control.Label
        MinimumSizeForBlobDetectionPixelsEditField  matlab.ui.control.NumericEditField
        CameraHeight                    matlab.ui.container.ButtonGroup
        Height1                         matlab.ui.control.ToggleButton
        Height2                         matlab.ui.control.ToggleButton
        Height3                         matlab.ui.control.ToggleButton
        Height4                         matlab.ui.control.ToggleButton
        Height5                         matlab.ui.control.ToggleButton
        Height6                         matlab.ui.control.ToggleButton
        SelectCameraHeightLabel         matlab.ui.control.Label
        MinimumNIRValueEditFieldLabel   matlab.ui.control.Label
        MinimumNIRValueEditField        matlab.ui.control.NumericEditField
        ImageCaptureTab                 matlab.ui.container.Tab
        CaptureNewImageButton           matlab.ui.control.Button
        LoadNewImageButton              matlab.ui.control.Button
        BlobExtractionButton            matlab.ui.control.Button
        PerformAnalysisButton           matlab.ui.control.Button
        MicaSenseBlue                   matlab.ui.control.UIAxes
        MicaSenseGreen                  matlab.ui.control.UIAxes
        MicaSenseRed                    matlab.ui.control.UIAxes
        MicaSenseNearIR                 matlab.ui.control.UIAxes
        MicaSenseRedEdge                matlab.ui.control.UIAxes
        MicaSenseRGB                    matlab.ui.control.UIAxes
        ProcessingImagesTab             matlab.ui.container.Tab
        Value                           matlab.ui.control.UIAxes
        Saturation                      matlab.ui.control.UIAxes
        Hue                             matlab.ui.control.UIAxes
        HueMask                         matlab.ui.control.UIAxes
        SaturationMask                  matlab.ui.control.UIAxes
        ValueMask                       matlab.ui.control.UIAxes
        BinaryColored                   matlab.ui.control.UIAxes
        BlobExtractsTab                 matlab.ui.container.Tab
        BlobExtract1Data                matlab.ui.control.Label
        BlobImageExtract1               matlab.ui.control.Button
        BlobImageExtract3               matlab.ui.control.Button
        BlobImageExtract2               matlab.ui.control.Button
        BlobImageExtract4               matlab.ui.control.Button
        BlobExtractLabel_2              matlab.ui.control.Label
        BlobExtractLabel_3              matlab.ui.control.Label
        BlobExtractLabel                matlab.ui.control.Label
        BlobExtractLabel_4              matlab.ui.control.Label
        BlobExtract3DataTitle           matlab.ui.control.Label
        BlobExtract4DataTitle           matlab.ui.control.Label
        BlobExtract2DataTitle           matlab.ui.control.Label
        BlobExtract1DataTitle           matlab.ui.control.Label
        BlobExtract2Data                matlab.ui.control.Label
        BlobExtract3Data                matlab.ui.control.Label
        BlobExtract4Data                matlab.ui.control.Label
        RemoveBlob1Button               matlab.ui.control.Button
        RemoveBlob2Button               matlab.ui.control.Button
        RemoveBlob3Button               matlab.ui.control.Button
        RemoveBlob4Button               matlab.ui.control.Button
        PreviousPageButton              matlab.ui.control.Button
        NextPageButton                  matlab.ui.control.Button
        SetCropIDEditField_5Label       matlab.ui.control.Label
        SetCropIDEditField_1            matlab.ui.control.EditField
        SetCropIDEditField_6Label       matlab.ui.control.Label
        SetCropIDEditField_2            matlab.ui.control.EditField
        SetCropIDEditField_3Label       matlab.ui.control.Label
        SetCropIDEditField_3            matlab.ui.control.EditField
        SetCropIDEditField_4Label       matlab.ui.control.Label
        SetCropIDEditField_4            matlab.ui.control.EditField
        CalculatedIndicesTab            matlab.ui.container.Tab
        SelectedImage                   matlab.ui.control.UIAxes
        SelectImageButtonGroup          matlab.ui.container.ButtonGroup
        NDVIFalseColorButton            matlab.ui.control.ToggleButton
        NearInfraredButton              matlab.ui.control.ToggleButton
        CIRCompositeButton              matlab.ui.control.ToggleButton
        NDVIRedButton                   matlab.ui.control.ToggleButton
        NDVIGreenButton                 matlab.ui.control.ToggleButton
        NDVIBlueButton                  matlab.ui.control.ToggleButton
        ENDVIButton                     matlab.ui.control.ToggleButton
        DataViewTab                     matlab.ui.container.Tab
        ExtractedData                   matlab.ui.control.Table
        ExtractedCropDataLabel          matlab.ui.control.Label
        ExportDataToXLSButton           matlab.ui.control.Button
    end


    properties (Access = private)
        blueBandExt % Description
        blueBandImage % Description
        blueBandName %
        blueBandPath %
        blueBandRegistered %
        blueBandSingle %
        cirCompImage %
        cirCompIntImage %
        colorBlobImages %
        cropArea %
        cropBinary %
        cropBinaryClumped %
        cropBlueNDVI %
        cropENDVI %
        cropGreenNDVI %
        cropNIR %
        cropMeanBlueNDVI %
        cropMeanENDVI %
        cropMeanGreenNDVI % Description
        cropMeanNIR %
        cropMeanRedNDVI %
        cropPixelList %
        cropProperties %
        cropRedNDVI %
        currentFolder %
        dataHeaders %
        endviImage %
        endviIntImage %
        exportData %
        exportFolderName %
        exportFolderPath %
        greenBandImage %
        greenBandName %
        greenBandRegistered % 
        greenBandSingle %
        hImage %
        hslumImage %
        hueMask %
        nirBandImage %
        nirBandSingle %
        labeledClumped %
        labeledColorBlob %
        ndviFalseColorImage %
        ndviFalseColorIntImage %
        ndviImageBlue %
        ndviImageGreen %
        ndviImageRed %
        nearRedBandImage %
        nearRedBandRegistered %
        nearRedBandSingle %
        numberOfCrops %
        numPlots %
        ndviBlueIntImage %
        ndviGreenIntImage %
        ndviRedIntImage, %
        redBandImage %
        redBandName %
        redBandRegistered %
        redBandSingle %
        rgbCombinedImage %
        saturationMask %
        sImage %
        uint16CombinedImage %
        valueMask %
        lumImage %
        nirBandName % 
        nearRedBandName % 
        minimumPixelArea % 
        clumpingDistance % 
        hueThresholdLow %
        hueThresholdHigh % 
        luminanceThresholdLow % 
        luminanceThresholdHigh % 
        saturationThresholdLow % 
        saturationThresholdHigh % 
        showBlobExtract %
        holeFillArea % Description
        cropRGB % Description
        cropMeanRGB %
        layerIndex % Description
        cameraHeight % Description
        croppedNDVIFalseColorImages % Description
        ndviFalseColormap % Description
        ndviFalseColorExtract % Description
        rgbCombinedImageSingle % Description
        extractedBlobID % Description
        currentBlobPageNumber % Description
        minNIRValue % Description
    end

    methods (Access = private)
    
        function openImages(app) 
            % Reset the calculated indices for new image
            app.ndviFalseColorImage = NaN(0);
            app.cirCompImage = NaN(0);
            app.ndviImageRed = NaN(0);
            app.ndviImageBlue = NaN(0);
            app.ndviImageGreen = NaN(0);
            app.endviImage = NaN(0);
            app.currentBlobPageNumber = 1;
            
            % Changing the number on the end of the filename to match the correct band
            app.greenBandName = strcat(strcat(app.blueBandName(1:end-1),'2'), app.blueBandExt);
            app.redBandName = strcat(strcat(app.blueBandName(1:end-1),'3'), app.blueBandExt);
            app.nearRedBandName = strcat(strcat(app.blueBandName(1:end-1),'4'), app.blueBandExt);
            app.nirBandName = strcat(strcat(app.blueBandName(1:end-1),'5'), app.blueBandExt);

            % Scaling the images
            %minValue = min([app.redBandImage(:);app.greenBandImage(:);app.blueBandImage(:)]);
            %maxValue = max([app.redBandImage(:);app.greenBandImage(:);app.blueBandImage(:)]);
            %app.redBandImage = scaledata(app.redBandImage, minValue, maxValue);
            %app.greenBandImage = scaledata(app.greenBandImage, minValue, maxValue);
            %app.blueBandImage = scaledata(app.blueBandImage, minValue, maxValue);
            
            % Displaying in the app
            imshow(app.greenBandImage,'Parent', app.MicaSenseGreen);
            imshow(app.blueBandImage,'Parent', app.MicaSenseBlue);
            imshow(app.redBandImage,'Parent', app.MicaSenseRed);
            imshow(app.nearRedBandImage,'Parent', app.MicaSenseRedEdge);
            imshow(app.nirBandImage,'Parent', app.MicaSenseNearIR);
            drawnow;
            
            % Registering the images
            title(app.MicaSenseRGB, 'Combined RGB Image (PROCESSING)');
            drawnow;
            [ app.redBandRegistered, app.nearRedBandRegistered, app.greenBandRegistered, app.blueBandRegistered, app.nirBandImage, app.rgbCombinedImage] = RegisterImages( app.redBandImage, app.nearRedBandImage, app.greenBandImage, app.blueBandImage, app.nirBandImage );
            
            % Show RGB image
            title(app.MicaSenseRGB, 'Combined RGB Image');
            imshow(app.rgbCombinedImage,'Parent', app.MicaSenseRGB);
            drawnow;
            
            % Show images if that option is already selected
            if app.SelectImageButtonGroup.SelectedObject == app.NDVIFalseColorButton
                imshow(app.ndviFalseColorImage, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NearInfraredButton
                imshow(app.nirBandImage, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.CIRCompositeButton
                imshow(app.cirCompImage, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NDVIRedButton
                imshow(app.ndviImageRed, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NDVIGreenButton
                imshow(app.ndviImageGreen, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NDVIBlueButton
                imshow(app.ndviImageBlue, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.ENDVIButton
                imshow(app.endviImage, 'Parent', app.SelectedImage);
            end
        end
        
        function createDataTable(app)
            % Creating the Excel column headers
	    app.dataHeaders = strings(2,16);
	    app.dataHeaders(1,1) = 'Crop Number';
	    app.dataHeaders(1,2) = 'Mean Red Value';
	    app.dataHeaders(1,3) = 'Mean Green Value';
	    app.dataHeaders(1,4) = 'Mean Blue Value';
	    app.dataHeaders(1,5) = 'Mean NIR Value';
	    app.dataHeaders(1,6) = 'NDVI Red Value';
	    app.dataHeaders(1,7) = 'NDVI Green Value';
	    app.dataHeaders(1,8) = 'NDVI Blue Value';
	    app.dataHeaders(1,9) = 'ENDVI Value';
	    app.dataHeaders(1,10) = 'Approximate Area (cm²)';
            app.dataHeaders(1,12) = 'Hue Threshold Low';
            app.dataHeaders(2,12) = app.HueThresholdLowSlider.Value;
	    app.dataHeaders(1,13) = 'Hue Threshold High';
            app.dataHeaders(2,13) = app.HueThresholdHighSlider.Value;
	    app.dataHeaders(1,14) = 'Saturation Threshold Low';
            app.dataHeaders(2,14) = app.SaturationThresholdLowSlider.Value;
	    app.dataHeaders(1,15) = 'Saturation Threshold High';
            app.dataHeaders(2,15) = app.SaturationThresholdHighSlider.Value;
	    app.dataHeaders(1,16) = 'Luminance Threshold Low';
            app.dataHeaders(2,16) = app.LuminanceThresholdLowSlider.Value;
	    app.dataHeaders(1,17) = 'Luminance Threshold High'; 
            app.dataHeaders(2,17) = app.LuminanceThresholdHighSlider.Value;
	    app.exportData = cellstr(app.dataHeaders); 
     
            app.ExtractedData.Data = app.exportData;
        end
        
        function exportImages(app)
            % Export the RGB image
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - RGB.png');
	    imwrite(app.rgbCombinedImage, exportFileName, 'png');
	
	    % Export the NDVI images
	    nvdiRedIntImage = im2uint8(app.ndviImageRed);
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - NVDI Red.png');
	    imwrite(nvdiRedIntImage, exportFileName, 'png');
	    nvdiGreenIntImage = im2uint8(app.ndviImageRed);
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - NVDI Green.png');
	    imwrite(nvdiGreenIntImage, exportFileName, 'png');
	    nvdiBlueIntImage = im2uint8(app.ndviImageRed);
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - NVDI Blue.png');
	    imwrite(nvdiBlueIntImage, exportFileName, 'png');
		
	    % Export the ENDVI image
	    envdiIntImage = im2uint8(app.endviImage);
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - ENDVI.png');
	    imwrite(envdiIntImage, exportFileName, 'png');
	
	    % Export the NDVI False Color Image
	    app.ndviFalseColorIntImage = im2uint8(app.ndviFalseColorImage);
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - NVDI False Color.png');
	    imwrite(app.ndviFalseColorIntImage, exportFileName, 'png');

	    % Export the CIR Composite image
	    app.cirCompIntImage = im2uint8(app.cirCompImage);
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - CIR.png');
	    imwrite(app.cirCompIntImage, exportFileName, 'png');
        
	    % Export the color blob image
	    exportFileName = strcat(app.blueBandName(1:end-2), ' - Colored Blob.png');
	    imwrite(app.labeledColorBlob, exportFileName, 'png');
        
	    % Exporting each extracted blob and cropped NDVI false color image
	    for i = 1 : app.numberOfCrops
                exportFileName = strcat(app.blueBandName(1:end-2), sprintf(' - Extract %d.png', i));
		imwrite(app.colorBlobImages{i}, exportFileName, 'png');
  
                exportFileName = strcat(app.blueBandName(1:end-2), sprintf(' - NDVI False Color Extract %d.png', i));
                imwrite(app.croppedNDVIFalseColorImages{i}, exportFileName, 'png');
	    end
        end
        
        function rebuildDataTable(app)
            % Reinitialize the table to be blank
            createDataTable;
            
            for i = 1 : app.numberOfCrops
                d
            end
        end
        
        function removeBlobData(app, blobRemoveNumber)
            
        end
        
    end


    methods (Access = private)

        % Value changed function: HueThresholdLowSlider
        function HueThresholdLowSliderValueChanged(app, event)
            app.hueThresholdLow = app.HueThresholdLowSlider.Value;
            app.SetHueThresholdLowEditField.Value = app.HueThresholdLowSlider.Value;
        end

        % Value changed function: HueThresholdHighSlider
        function HueThresholdHighSliderValueChanged(app, event)
            app.hueThresholdHigh = app.HueThresholdHighSlider.Value;
            app.SetHueThresholdHighEditField.Value = app.HueThresholdHighSlider.Value;
        end

        % Button pushed function: ResetToDefaultHueValuesButton
        function ResetToDefaultHueValuesButtonPushed(app, event)
            app.hueThresholdLow = 0.25;
            app.hueThresholdHigh = 0.45;
            app.HueThresholdLowSlider.Value = 0.25;
            app.HueThresholdHighSlider.Value = .45;
            app.SetHueThresholdLowEditField.Value = 0.25;
            app.SetHueThresholdHighEditField.Value = 0.45;
        end

        % Value changed function: SaturationThresholdLowSlider
        function SaturationThresholdLowSliderValueChanged(app, event)
            app.saturationThresholdLow = app.SaturationThresholdLowSlider.Value;
            app.SetSaturationThresholdLowEditField.Value = app.SaturationThresholdLowSlider.Value;
        end

        % Value changed function: SaturationThresholdHighSlider
        function SaturationThresholdHighSliderValueChanged(app, event)
            app.saturationThresholdHigh = app.SaturationThresholdHighSlider.Value;
            app.SetSaturationThresholdHighEditField.Value = app.SaturationThresholdHighSlider.Value;
        end

        % Button pushed function: ResetToDefaultSaturationValues
        function ResetToDefaultSaturationValuesButtonPushed(app, event)
            app.saturationThresholdLow = 0.15;
            app.saturationThresholdHigh = 0.55;
            app.SaturationThresholdLowSlider.Value = 0.15;
            app.SaturationThresholdHighSlider.Value = 0.55;
            app.SetSaturationThresholdLowEditField.Value = 0.15;
            app.SetSaturationThresholdHighEditField.Value = 0.55;
        end

        % Value changed function: LuminanceThresholdLowSlider
        function LuminanceThresholdLowSliderValueChanged(app, event)
            app.luminanceThresholdLow = app.LuminanceThresholdLowSlider.Value;
            app.SetLuminanceThresholdLowEditField.Value = app.LuminanceThresholdLowSlider.Value;
        end

        % Value changed function: LuminanceThresholdHighSlider
        function LuminanceThresholdHighSliderValueChanged(app, event)
            app.luminanceThresholdHigh = app.LuminanceThresholdHighSlider.Value;
            app.SetLuminanceThresholdHighEditField.Value = app.LuminanceThresholdHighSlider.Value;
        end

        % Button pushed function: ResetToDefaultLuminanceValues
        function ResetToDefaultLuminanceValuesButtonPushed(app, event)
            app.luminanceThresholdLow = 0.05;
            app.luminanceThresholdHigh = 0.35;
            app.LuminanceThresholdLowSlider.Value = 0.05;
            app.LuminanceThresholdHighSlider.Value = 0.35;
            app.SetLuminanceThresholdLowEditField.Value = 0.05;
            app.SetLuminanceThresholdHighEditField.Value = 0.35;
        end

        % Button pushed function: CaptureNewImageButton
        function CaptureNewImageButtonPushed(app, event)
            % Call the function which captures a new image then saves and returns them
            [ app.greenBandImage, app.blueBandImage, app.redBandImage, app.nearRedBandImage, app.nirBandImage, app.blueBandPath, app.blueBandName, app.blueBandExt ] = CaptureMicaSenseImage( );
            
            % Opens the images for analysis and in the GUI
            openImages(app);
        end

        % Button pushed function: LoadNewImageButton
        function LoadNewImageButtonPushed(app, event)
            [app.blueBandImage, app.currentFolder] = uigetfile({'*bmp;,*.jpg;*.tif;*.png;*.gif','Image Files';...
                '*.*','All Files' }, 'Specify Blue Band (_1) Image'); % Select and image file
            
            cd(app.currentFolder); % Change working dir
            
            if ~exist(app.blueBandImage, 'file') % If the image doesn't exist, exit
                imageError = sprintf('There was no image selected.');
                uiwait(warndlg(imageError));
                return;
            end
            
            % Extracts the different parts of the filename
            [app.blueBandPath, app.blueBandName, app.blueBandExt] = fileparts(app.blueBandImage); 
            
            % Changing the number on the end of the filename to match the correct band
            app.greenBandName = strcat(strcat(app.blueBandName(1:end-1),'2'), app.blueBandExt);
            app.redBandName = strcat(strcat(app.blueBandName(1:end-1),'3'), app.blueBandExt);
            app.nearRedBandName = strcat(strcat(app.blueBandName(1:end-1),'4'), app.blueBandExt);
            app.nirBandName = strcat(strcat(app.blueBandName(1:end-1),'5'), app.blueBandExt);
     
            % Opening the images
            app.blueBandImage = imread(app.blueBandImage);
            app.greenBandImage = imread(app.greenBandName);
            app.redBandImage = imread(app.redBandName); 
            app.nirBandImage = imread(app.nirBandName);
            app.nearRedBandImage = imread(app.nearRedBandName);
            
            % Open the images for analysis and in the GUI
            openImages(app);
        end

        % Button pushed function: BlobExtractionButton
        function BlobExtractionButtonPushed(app, event)
            app.cropProperties = NaN(0);
            app.numberOfCrops = NaN(0);
            app.colorBlobImages = NaN(0);
            app.cropArea = NaN(0);
            app.cropPixelList = NaN(0);
            app.layerIndex = NaN(0);
            app.cropRGB = NaN(0);
            app.cropMeanRGB = NaN(0);
            app.exportData = NaN(0);
            app.labeledClumped = NaN(0);
            app.currentBlobPageNumber = 1;
            format short;
            
            % Create the data table
            createDataTable(app);
            
            % Getting all of the settings values
            app.hueThresholdLow = app.HueThresholdLowSlider.Value;
            app.hueThresholdHigh = app.HueThresholdHighSlider.Value;
            app.saturationThresholdLow = app.SaturationThresholdLowSlider.Value;
            app.saturationThresholdHigh = app.SaturationThresholdHighSlider.Value;
            app.luminanceThresholdLow = app.LuminanceThresholdLowSlider.Value;
            app.luminanceThresholdHigh = app.LuminanceThresholdHighSlider.Value;
            app.clumpingDistance = app.BlobClumpingDistanceEditField.Value;
            app.minimumPixelArea = app.MinimumSizeForBlobDetectionPixelsEditField.Value;
            app.holeFillArea = app.MaximumAreaToFillPixelsEditField.Value;
            
            % Converting the image to Hue only, saturation only, and value only and NIR single
            %app.hslumImage = rgb2hsv(app.rgbCombinedImage);
            [app.hImage, app.sImage, app.lumImage] = rgb2hsl(app.rgbCombinedImage);
            app.nirBandSingle = im2single(app.nirBandImage);
            %app.hImage = app.hslumImage(:,:,1);
            %app.sImage = app.hslumImage(:,:,2);
            %app.lumImage = app.hslumImage(:,:,3);
            	
            % Apply the thresholds to create the Hue, Saturation, and Value mask images
            app.hueMask = (app.hImage >= app.hueThresholdLow) & (app.hImage <= app.hueThresholdHigh) & (app.nirBandSingle >= app.minNIRValue);
            app.saturationMask = (app.sImage >= app.saturationThresholdLow) & (app.sImage <= app.saturationThresholdHigh) & (app.nirBandSingle >= app.minNIRValue);
            app.valueMask = (app.lumImage >= app.luminanceThresholdLow) & (app.lumImage <= app.luminanceThresholdHigh) & (app.nirBandSingle >= app.minNIRValue);
     
            % Display the HSV images
            imshow(app.hImage, 'Parent', app.Hue);
            imshow(app.sImage, 'Parent', app.Saturation);
            imshow(app.lumImage, 'Parent', app.Value);
            drawnow;
     
            % Display the HSV Mask images
            imshow(app.hueMask, 'Parent', app.HueMask);
            imshow(app.saturationMask, 'Parent', app.SaturationMask);
            imshow(app.valueMask, 'Parent', app.ValueMask);
            drawnow;
            
            % Creating the binary image by combining the H,S, and V masks
            % Where they all overlap is considered part of the binary image
            app.cropBinary = app.hueMask & app.saturationMask & app.valueMask; % Adding the masks together
            minimumSize = app.minimumPixelArea; % Minimum area of a blob
            app.cropBinary = bwareaopen(app.cropBinary, minimumSize); % Lose areas that are less than minimumSize
            
            % Filling the small holes found in the crops
            filledMask = imfill(logical(app.cropBinary), 'holes'); % Fills all holes in the image
            allHoles = filledMask & ~app.cropBinary; % Find all pixels considered holes
            largeHoles = bwareaopen(allHoles, app.holeFillArea); % Find only those larger than holeFillArea
            smallHoles = allHoles & ~largeHoles; % Find the small holes
            app.cropBinary = app.cropBinary | smallHoles; % Adds the holes to the original image
     
            % Clumping together very close blobs
            app.cropBinaryClumped = bwdist(app.cropBinary) <= app.clumpingDistance; % Clumps close
            app.labeledClumped = labelmatrix(bwconncomp(app.cropBinaryClumped)); % Labels these if close
            app.labeledClumped(~app.cropBinary) = 0; % Removes the extra data created by clumping
            app.labeledColorBlob = label2rgb(app.labeledClumped, 'hsv', 'k', 'shuffle'); % RGB Labeled image
     
            % Display the colored binary images
            imshow(app.labeledColorBlob, 'Parent', app.BinaryColored);
            
            % Extracting out each individual blob
            app.cropProperties = regionprops(app.labeledClumped, 'Area', 'BoundingBox', 'Centroid', 'PixelList');
            app.numberOfCrops = size(app.cropProperties, 1);
     
            % Create a 0-1 RGB image
            app.rgbCombinedImageSingle = im2single(app.rgbCombinedImage);
            
            % Finding the RGB and mean RGB values for each crop
            for i = 1 : app.numberOfCrops
                % Getting the indices for each of the crops
            	app.cropPixelList{i} = app.cropProperties(i).PixelList;  % Get list of pixels in current blob.
            	layerSize = [size(app.rgbCombinedImage, 1), size(app.rgbCombinedImage, 2)]; % 
            	layerPixelCount = prod(layerSize); % Total pixels in original image
            	app.layerIndex{i} = sub2ind(layerSize, app.cropPixelList{i}(:,2), app.cropPixelList{i}(:,1)); % Converting pixel list to indices
                
                % Create an RGB image without any noncrop pixels
                rgbCombinedImageExtract = bsxfun(@times, app.rgbCombinedImage, cast(app.cropBinary, 'like', app.rgbCombinedImage));
                app.colorBlobImages{i} = imcrop(rgbCombinedImageExtract, app.cropProperties(i).BoundingBox); % Adds the new cropped image
             
                % Calculating the approximate area of the plants given a specific height
                if app.cameraHeight == 1
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 125.0; % Gets the area of the blob. At 54" 125 pix/cm^2
                elseif app.cameraHeight == 2
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 2.0; % Gets the area of the blob FIX WHEN PIX/CM MEASURED
                elseif app.cameraHeight == 3
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 3.0; % Gets the area of the blob FIX WHEN PIX/CM MEASURED
                elseif app.cameraHeight == 4
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 4.0; % Gets the area of the blob FIX WHEN PIX/CM MEASURED
                elseif app.cameraHeight == 5
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 5.0; % Gets the area of the blob FIX WHEN PIX/CM MEASURED
                elseif app.cameraHeight == 6
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 6.0; % Gets the area of the blob FIX WHEN PIX/CM MEASURED
                else % If selection unknown, assume it is height 1
                    app.cropArea{i} = typecast(app.cropProperties(i).Area,'double') / 125.0; % Gets the area of the blob FIX WHEN PIX/CM MEASURED
                end
             
                
            	app.cropRGB{i}(:,1) = app.rgbCombinedImageSingle(app.layerIndex{i}); % Getting the red values from the image
            	app.cropRGB{i}(:,2) = app.rgbCombinedImageSingle(app.layerIndex{i} + layerPixelCount); % Getting the green values from the image
            	app.cropRGB{i}(:,3) = app.rgbCombinedImageSingle(app.layerIndex{i} + 2*layerPixelCount); % Getting the blue values from the image
            	app.cropMeanRGB(i,:) = mean(app.cropRGB{i}); % Finding the mean of the RGB values
             
                % Adding data to table
                if i == 1
                    app.exportData{i+1, 1} = app.SetCropIDEditField_1.Value;
                elseif i == 2
                    app.exportData{i+1, 1} = app.SetCropIDEditField_2.Value;
                elseif i == 3
                    app.exportData{i+1, 1} = app.SetCropIDEditField_3.Value;
                elseif i == 4
                    app.exportData{i+1, 1} = app.SetCropIDEditField_4.Value;
                else 
                    app.exportData{i+1, 1} = i; % Blob number
                    app.extractedBlobID{i} = i;
                end
            	app.exportData{i+1, 2} = app.cropMeanRGB(i,1); % Red value
            	app.exportData{i+1, 3} = app.cropMeanRGB(i,2); % Green value
            	app.exportData{i+1, 4} = app.cropMeanRGB(i,3); % Blue value
                app.exportData{i+1, 10} = app.cropArea{i}; % Approximate area of crop
            end
     
            % Refreshing the table
            app.ExtractedData.Data = app.exportData;
            
            % Showing the first four images in the blob extracts tab
            if app.numberOfCrops < 4
                for i = 1 : app.numberOfCrops
                    strI = int2str(i);
                    exportFileName = strcat(strcat('CurrentBlobExtract_', strI),'.png');
            	    imwrite(app.colorBlobImages{i}, exportFileName, 'png');
                end
                for i = app.numberOfCrops + 1 : 4
                    strI = int2str(i);
                    exportFileName = strcat(strcat('CurrentBlobExtract_', strI),'.png');
                    imwrite(imresize(0,[300 400]), exportFileName, 'png');
                end
            end
            if app.numberOfCrops >= 4
                for i = 1 : 4
                    strI = int2str(i);
                    exportFileName = strcat(strcat('CurrentBlobExtract_', strI),'.png');
            	    imwrite(imresize(app.colorBlobImages{i},[300 400]), exportFileName, 'png');
                end
            end
            
            % Show the icon
            app.BlobImageExtract1.Icon = 'CurrentBlobExtract_1.png';
            app.BlobImageExtract2.Icon = 'CurrentBlobExtract_2.png';
            app.BlobImageExtract3.Icon = 'CurrentBlobExtract_3.png';
            app.BlobImageExtract4.Icon = 'CurrentBlobExtract_4.png';
          
            % Set the data text on the blob extracts tab
            app.BlobExtract1Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            app.BlobExtract2Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            app.BlobExtract3Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            app.BlobExtract4Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            
            if app.numberOfCrops >= 1
                app.BlobExtract1Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{2, 2}, app.exportData{2, 3}, app.exportData{2, 4}, app.exportData{2, 10});
            end
            if app.numberOfCrops >= 2
                app.BlobExtract2Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{3, 2}, app.exportData{3, 3}, app.exportData{3, 4}, app.exportData{3, 10});
            end
            if app.numberOfCrops >= 3
                app.BlobExtract3Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{4, 2}, app.exportData{4, 3}, app.exportData{4, 4}, app.exportData{4, 10});
            end
            if app.numberOfCrops >= 4
                app.BlobExtract4Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{5, 2}, app.exportData{5, 3}, app.exportData{5, 4}, app.exportData{5, 10});
            end                                   
        end

        % Value changed function: SetHueThresholdLowEditField
        function SetHueThresholdLowEditFieldValueChanged(app, event)
            app.hueThresholdLow = app.SetHueThresholdLowEditField.Value;
            app.HueThresholdLowSlider.Value = app.SetHueThresholdLowEditField.Value;
        end

        % Value changed function: SetHueThresholdHighEditField
        function SetHueThresholdHighEditFieldValueChanged(app, event)
            app.hueThresholdHigh = app.SetHueThresholdHighEditField.Value;
            app.HueThresholdHighSlider.Value = app.SetHueThresholdHighEditField.Value;
        end

        % Value changed function: SetSaturationThresholdLowEditField
        function SetSaturationThresholdLowEditFieldValueChanged(app, event)
            app.saturationThresholdLow = app.SetSaturationThresholdLowEditField.Value;
            app.SaturationThresholdLowSlider.Value = app.SetSaturationThresholdLowEditField.Value;
        end

        % Value changed function: SetSaturationThresholdHighEditField
        function SetSaturationThresholdHighEditFieldValueChanged(app, event)
            app.saturationThresholdHigh = app.SetSaturationThresholdHighEditField.Value;
            app.SaturationThresholdHighSlider.Value = app.SetSaturationThresholdHighEditField.Value;
        end

        % Value changed function: SetLuminanceThresholdLowEditField
        function SetLuminanceThresholdLowEditFieldValueChanged(app, event)
            app.luminanceThresholdLow = app.SetLuminanceThresholdLowEditField.Value;
            app.LuminanceThresholdLowSlider.Value = app.SetLuminanceThresholdLowEditField.Value;
        end

        % Value changed function: SetLuminanceThresholdHighEditField
        function SetLuminanceThresholdHighEditFieldValueChanged(app, event)
            app.luminanceThresholdHigh = app.SetLuminanceThresholdHighEditField.Value;
            app.LuminanceThresholdHighSlider.Value = app.SetLuminanceThresholdHighEditField.Value;
        end

        % Button pushed function: PerformAnalysisButton
        function PerformAnalysisButtonPushed(app, event)
            % Converting images to single data type
            app.nirBandSingle = im2single(app.nirBandImage);
            app.redBandSingle = im2single(app.redBandRegistered);
            app.greenBandSingle = im2single(app.greenBandRegistered);
            app.blueBandSingle = im2single(app.blueBandRegistered);
            app.nearRedBandSingle = im2single(app.nearRedBandRegistered);
            		
            % Creating the various NDVI images
            app.ndviImageRed = (app.nirBandSingle - app.redBandSingle) ./ (app.nirBandSingle + app.redBandSingle);
            app.ndviImageGreen = (app.nirBandSingle - app.greenBandSingle) ./ (app.nirBandSingle + app.greenBandSingle);
            app.ndviImageBlue = (app.nirBandSingle - app.blueBandSingle) ./ (app.nirBandSingle + app.blueBandSingle);
            		
            % Creating ENDVI image
            app.endviImage = ((app.nirBandSingle + app.greenBandSingle) - (2 * app.blueBandSingle)) ./ ((app.nirBandSingle + app.greenBandSingle) + (2 * app.blueBandSingle));
            		
            % Convert the NDVI image to color using the MakeNDVIFalseColor function
            app.ndviFalseColorImage = MakeNDVIFalseColor(app.ndviImageRed);
            
            % Create a color infrared composite image where red is NIR band, 
            % green is the visible red band, and blue is the visible green band
            app.cirCompImage = cat(3, app.nirBandImage, app.redBandRegistered, app.greenBandRegistered);
     
            % Mask the image using bsxfun() function
            ndviFalseColorImageExtract = bsxfun(@times, app.ndviFalseColorImage, cast(app.cropBinary, 'like', app.ndviFalseColorImage));
            
            % Adding the colorbar legend to the false color iamge
            app.ndviFalseColorImage = legendNDVIFC(app.ndviFalseColorImage);
            
            % Data gathering from the created images
            for i = 1 : app.numberOfCrops
                % Adding a cropped version of the false color
                
                app.croppedNDVIFalseColorImages{i} = imcrop(ndviFalseColorImageExtract, app.cropProperties(i).BoundingBox);
                
                % Calculate mean IR
            	app.cropNIR{i} = app.nirBandSingle(app.layerIndex{i});
            	app.cropMeanNIR{i} = mean(app.cropNIR{i});
            		
            	% Calculating mean NDVI values
            	app.cropRedNDVI{i} = app.ndviImageRed(app.layerIndex{i});
            	app.cropMeanRedNDVI{i} = mean(app.cropRedNDVI{i});
            	app.cropGreenNDVI{i} = app.ndviImageGreen(app.layerIndex{i});
            	app.cropMeanGreenNDVI{i} = mean(app.cropGreenNDVI{i});
            	app.cropBlueNDVI{i} = app.ndviImageBlue(app.layerIndex{i});
            	app.cropMeanBlueNDVI{i} = mean(app.cropBlueNDVI{i});
            			
            	% Calculating ENDVI
            	app.cropENDVI{i} = app.endviImage(app.layerIndex{i}); 
            	app.cropMeanENDVI{i} = mean(app.cropENDVI{i});
                
                % Add data to table
            	app.exportData{i+1, 5} = app.cropMeanNIR{i}; % Mean IR Value from 0 to 1
            	app.exportData{i+1, 6} = app.cropMeanRedNDVI{i}; % Mean NDVI Red Value from 0 to 1
            	app.exportData{i+1, 7} = app.cropMeanGreenNDVI{i}; % Mean NDVI Green Value from 0 to 1
            	app.exportData{i+1, 8} = app.cropMeanBlueNDVI{i}; % Mean NDVI Blue Value from 0 to 1
            	app.exportData{i+1, 9} = app.cropMeanENDVI{i}; % Mean ENDVI Value from 0 to 1
            end
            
            % Refreshing the table
            app.ExtractedData.Data = app.exportData;
            
            % Show images if that option is already selected
            if app.SelectImageButtonGroup.SelectedObject == app.NDVIFalseColorButton
                imshow(app.ndviFalseColorImage, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NearInfraredButton
                imshow(app.nirBandImage, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.CIRCompositeButton
                imshow(app.cirCompImage, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NDVIRedButton
                imshow(app.ndviImageRed, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NDVIGreenButton
                imshow(app.ndviImageGreen, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.NDVIBlueButton
                imshow(app.ndviImageBlue, 'Parent', app.SelectedImage);
            elseif app.SelectImageButtonGroup.SelectedObject == app.ENDVIButton
                imshow(app.endviImage, 'Parent', app.SelectedImage);
            end
            
            % Setting data text
            if app.numberOfCrops >= 1
                app.BlobExtract1Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nIR Value: %.3f\n\nNDVI Red Value: %.3f\n\nNDVI Green Value: %.3f\n\nNDVI Blue Value: %.3f\n\nENDVI Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{2, 2}, app.exportData{2, 3}, app.exportData{2, 4}, app.exportData{2, 5}, app.exportData{2, 6}, app.exportData{2, 7}, app.exportData{2, 8}, app.exportData{2, 9}, app.exportData{2, 10});
            end
            if app.numberOfCrops >= 2
                app.BlobExtract2Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nIR Value: %.3f\n\nNDVI Red Value: %.3f\n\nNDVI Green Value: %.3f\n\nNDVI Blue Value: %.3f\n\nENDVI Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{3, 2}, app.exportData{3, 3}, app.exportData{3, 4}, app.exportData{3, 5}, app.exportData{3, 6}, app.exportData{3, 7}, app.exportData{3, 8}, app.exportData{3, 9}, app.exportData{3, 10});
            end
            if app.numberOfCrops >= 3
                app.BlobExtract3Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nIR Value: %.3f\n\nNDVI Red Value: %.3f\n\nNDVI Green Value: %.3f\n\nNDVI Blue Value: %.3f\n\nENDVI Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{4, 2}, app.exportData{4, 3}, app.exportData{4, 4}, app.exportData{4, 5}, app.exportData{4, 6}, app.exportData{4, 7}, app.exportData{4, 8}, app.exportData{4, 9}, app.exportData{4, 10});
            end
            if app.numberOfCrops >= 4
                app.BlobExtract4Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nIR Value: %.3f\n\nNDVI Red Value: %.3f\n\nNDVI Green Value: %.3f\n\nNDVI Blue Value: %.3f\n\nENDVI Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{5, 2}, app.exportData{5, 3}, app.exportData{5, 4}, app.exportData{5, 5}, app.exportData{5, 6}, app.exportData{5, 7}, app.exportData{5, 8}, app.exportData{5, 9}, app.exportData{5, 10});
            end
        end

        % Selection changed function: SelectImageButtonGroup
        function SelectImageButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectImageButtonGroup.SelectedObject;
            
            if selectedButton == app.NDVIFalseColorButton
                imshow(app.ndviFalseColorImage, 'Parent', app.SelectedImage); 
                app.SelectedImage.Title.String = 'NDVI False Color Image';
                colorbar(app.SelectedImage,'off');
            end
            if selectedButton == app.NearInfraredButton
                imshow(app.nirBandImage, 'Parent', app.SelectedImage);
                app.SelectedImage.Title.String = 'Near Infrared Image';
                colorbar(app.SelectedImage,'off');
            end
            if selectedButton == app.CIRCompositeButton
                imshow(app.cirCompImage, 'Parent', app.SelectedImage);
                app.SelectedImage.Title.String = 'Color Infrared (CIR) Composite Image';
                colorbar(app.SelectedImage,'off');
            end
            if selectedButton == app.NDVIRedButton
                imshow(app.ndviImageRed, 'Parent', app.SelectedImage);
                app.SelectedImage.Title.String = 'NDVI (Red) Image';
                colorbar(app.SelectedImage,'east');
            end
            if selectedButton == app.NDVIGreenButton
                imshow(app.ndviImageGreen, 'Parent', app.SelectedImage);
                app.SelectedImage.Title.String = 'NDVI (Green) Image';
                colorbar(app.SelectedImage,'east');
            end
            if selectedButton == app.NDVIBlueButton
                imshow(app.ndviImageBlue, 'Parent', app.SelectedImage);
                app.SelectedImage.Title.String = 'NDVI (Blue) Image';
                colorbar(app.SelectedImage,'east');
            end
            if selectedButton == app.ENDVIButton
                imshow(app.endviImage, 'Parent', app.SelectedImage);
                app.SelectedImage.Title.String = 'ENDVI Image';
                colorbar(app.SelectedImage,'east');
            end
        end

        % Callback function
        function SetMinimumAreaForBlobsPixelsEditFieldValueChanged(app, event)
            app.minimumPixelArea = app.MinimumSizeForBlobDetectionPixelsEditField.Value;
        end

        % Value changed function: BlobClumpingDistanceEditField
        function BlobClumpingDistanceEditFieldValueChanged(app, event)
            app.clumpingDistance = app.BlobClumpingDistanceEditField.Value;
        end

        % Button pushed function: ResetToDefaultBlobAnalysisButton
        function ResetToDefaultBlobAnalysisButtonPushed(app, event)
            app.clumpingDistance = 2.5;
            app.BlobClumpingDistanceEditField.Value = 2.5;
            app.minimumPixelArea = 250;
            app.MinimumSizeForBlobDetectionPixelsEditField.Value = 250;
            app.holeFillArea = 50;
            app.MaximumAreaToFillPixelsEditField.Value = 50;
            app.minNIRValue = .45;
            app.MinimumNIRValueEditField.Value = .45;
        end

        % Value changed function: MaximumAreaToFillPixelsEditField
        function MaximumAreaToFillPixelsEditFieldValueChanged(app, event)
            app.holeFillArea = app.MaximumAreaToFillPixelsEditField.Value;
        end

        % Button pushed function: ExportDataToXLSButton
        function ExportDataToXLSButtonPushed(app, event)
            % Creating a new folder for the image's data and created images
            app.exportFolderName = strcat(app.blueBandName(1:end-2), ' Processed Data');
            app.exportFolderPath = strcat('\', app.exportFolderName);
            app.exportFolderPath = strcat(app.exportFolderName, '\');
            exportDirCheck = fullfile(app.exportFolderPath); % Remove any old directory
            if exist(exportDirCheck, 'dir')
                rmdir(app.exportFolderPath,'s');
            end
            mkdir(app.exportFolderName); % Create the new directory 
            cd(app.exportFolderPath);
            	
            % Exporting the data to a data file
            exportFileName = strcat(app.blueBandName(1:end-2), ' - Data.xls');
            xlswrite(exportFileName, app.exportData);
            	    
    	    exportImages(app); % Exporting the various images
        
            cd('../'); % Return to previous folder
        end

        % Selection changed function: CameraHeight
        function CameraHeightSelectionChanged(app, event)
            selectedButton = app.CameraHeight.SelectedObject;
            if selectedButton == app.Height1
                app.cameraHeight = 1;
            end
            if selectedButton == app.Height2
                app.cameraHeight = 2;
            end
            if selectedButton == app.Height3
                app.cameraHeight = 3;
            end
            if selectedButton == app.Height4
                app.cameraHeight = 4;
            end
            if selectedButton == app.Height5
                app.cameraHeight = 5;
            end
            if selectedButton == app.Height6
                app.cameraHeight = 6;
            end
        end

        % Button pushed function: RemoveBlob1Button
        function RemoveBlob1ButtonPushed(app, event)
            removeBlobData(app, 1 + (4*(app.currentBlobPageNumber - 1)));
            rebuildDataTable(app);
        end

        % Value changed function: SetCropIDEditField_1
        function SetCropIDEditField_1ValueChanged(app, event)
            app.extractedBlobID{1 + (4*(app.currentBlobPageNumber - 1))} = app.SetCropIDEditField_1.Value;
            %rebuildDataTable(app);
        end

        % Button pushed function: NextPageButton
        function NextPageButtonPushed(app, event)
            % Set the page number
            app.currentBlobPageNumber = app.currentBlobPageNumber + 1;
            
            % Set the images
            
            
            % Set the data text
            
        end

        % Button pushed function: PreviousPageButton
        function PreviousPageButtonPushed(app, event)
            % Set the page number
            app.currentBlobPageNumber = app.currentBlobPageNumber - 1;
            
            % Showing the first four images in the blob extracts tab
            if app.numberOfCrops < 4 + (4*(app.currentBlobPageNumber - 1))
                for i = 1 + (4*(app.currentBlobPageNumber - 1)): app.numberOfCrops
                    strI = int2str(i);
                    exportFileName = strcat(strcat('CurrentBlobExtract_', strI),'.png');
            	    imwrite(app.colorBlobImages{i}, exportFileName, 'png');
                end
                for i = app.numberOfCrops + 1 : 4
                    strI = int2str(i);
                    exportFileName = strcat(strcat('CurrentBlobExtract_', strI),'.png');
                    imwrite(imresize(0,[300 400]), exportFileName, 'png');
                end
            end
            if app.numberOfCrops >= 4 + (4*(app.currentBlobPageNumber - 1))
                for i = 1 + (4*(app.currentBlobPageNumber - 1)): 4 + (4*(app.currentBlobPageNumber - 1))
                    strI = int2str(i);
                    exportFileName = strcat(strcat('CurrentBlobExtract_', strI),'.png');
            	    imwrite(imresize(app.colorBlobImages{i},[300 400]), exportFileName, 'png');
                end
            end
            
            % Show the icon
            app.BlobImageExtract1.Icon = 'CurrentBlobExtract_1.png';
            app.BlobImageExtract2.Icon = 'CurrentBlobExtract_2.png';
            app.BlobImageExtract3.Icon = 'CurrentBlobExtract_3.png';
            app.BlobImageExtract4.Icon = 'CurrentBlobExtract_4.png';
          
            % Set the data text on the blob extracts tab
            app.BlobExtract1Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            app.BlobExtract2Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            app.BlobExtract3Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            app.BlobExtract4Data.Text = sprintf('Red Value:\n\nGreen Value:\n\nBlue Value:\n\nCrop Area (cm²):');
            
            if app.numberOfCrops >= 1
                app.BlobExtract1Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{2, 2}, app.exportData{2, 3}, app.exportData{2, 4}, app.exportData{2, 10});
            end
            if app.numberOfCrops >= 2
                app.BlobExtract2Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{3, 2}, app.exportData{3, 3}, app.exportData{3, 4}, app.exportData{3, 10});
            end
            if app.numberOfCrops >= 3
                app.BlobExtract3Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{4, 2}, app.exportData{4, 3}, app.exportData{4, 4}, app.exportData{4, 10});
            end
            if app.numberOfCrops >= 4
                app.BlobExtract4Data.Text = sprintf('Red Value: %.3f\n\nGreen Value: %.3f\n\nBlue Value: %.3f\n\nCrop Area (cm²): %.3f', app.exportData{5, 2}, app.exportData{5, 3}, app.exportData{5, 4}, app.exportData{5, 10});
            end
            
        end

        % Callback function
        function SetCropIDEditField_2ValueChanged(app, event)
            app.extractedBlobID{2 + (4*(app.currentBlobPageNumber - 1))} = app.SetCropIDEditField_2.Value;
            %rebuildDataTable(app);
        end

        % Callback function
        function SetCropIDEditField_3ValueChanged(app, event)
            app.extractedBlobID{3 + (4*(app.currentBlobPageNumber - 1))} = app.SetCropIDEditField_3.Value;
            %rebuildDataTable(app);
        end

        % Callback function
        function SetCropIDEditField_4ValueChanged(app, event)
            app.extractedBlobID{4 + (4*(app.currentBlobPageNumber - 1))} = app.SetCropIDEditField_4.Value;
            %rebuildDataTable(app);
        end

        % Button pushed function: RemoveBlob2Button
        function RemoveBlob2ButtonPushed(app, event)
            removeBlobData(app, 2 + (4*(app.currentBlobPageNumber - 1)));
        end

        % Button pushed function: RemoveBlob3Button
        function RemoveBlob3ButtonPushed(app, event)
            removeBlobData(app, 3 + (4*(app.currentBlobPageNumber - 1)));
        end

        % Button pushed function: RemoveBlob4Button
        function RemoveBlob4ButtonPushed(app, event)
            removeBlobData(app, 4 + (4*(app.currentBlobPageNumber - 1)));
        end

        % Value changed function: MinimumNIRValueEditField
        function MinimumNIRValueEditFieldValueChanged(app, event)
            app.minNIRValue = app.MinimumNIRValueEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KaciraLabCropAnalysisUIFigure = uifigure;
            app.KaciraLabCropAnalysisUIFigure.Name = 'Kacira Lab Crop Analysis';
            setAutoResize(app, app.KaciraLabCropAnalysisUIFigure, true)
            app.TabGroup = uitabgroup(app.KaciraLabCropAnalysisUIFigure);
            app.AnalysisSettingsTab = uitab(app.TabGroup);
            app.AnalysisSettingsTab.Title = 'Analysis Settings';
            app.AnalysisSettingsTab.Units = 'pixels';
            app.HueThresholdLowSliderLabel = uilabel(app.AnalysisSettingsTab);
            app.HueThresholdLowSliderLabel.Text = 'Hue Threshold Low';
            app.HueThresholdLowSlider = uislider(app.AnalysisSettingsTab);
            app.HueThresholdLowSlider.Limits = [0 1];
            app.HueThresholdLowSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HueThresholdLowSlider.ValueChangedFcn = createCallbackFcn(app, @HueThresholdLowSliderValueChanged, true);
            app.HueThresholdLowSlider.Value = 0.1;
            app.HueThresholdHighSliderLabel = uilabel(app.AnalysisSettingsTab);
            app.HueThresholdHighSliderLabel.Text = 'Hue Threshold High';
            app.HueThresholdHighSlider = uislider(app.AnalysisSettingsTab);
            app.HueThresholdHighSlider.Limits = [0 1];
            app.HueThresholdHighSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.HueThresholdHighSlider.ValueChangedFcn = createCallbackFcn(app, @HueThresholdHighSliderValueChanged, true);
            app.HueThresholdHighSlider.Value = 0.5;
            app.SaturationThresholdLowSliderLabel = uilabel(app.AnalysisSettingsTab);
            app.SaturationThresholdLowSliderLabel.Text = 'Saturation Threshold Low';
            app.SaturationThresholdLowSlider = uislider(app.AnalysisSettingsTab);
            app.SaturationThresholdLowSlider.Limits = [0 1];
            app.SaturationThresholdLowSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SaturationThresholdLowSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationThresholdLowSliderValueChanged, true);
            app.SaturationThresholdLowSlider.Value = 0.3;
            app.SaturationThresholdHighSliderLabel = uilabel(app.AnalysisSettingsTab);
            app.SaturationThresholdHighSliderLabel.Text = 'Saturation Threshold High';
            app.SaturationThresholdHighSlider = uislider(app.AnalysisSettingsTab);
            app.SaturationThresholdHighSlider.Limits = [0 1];
            app.SaturationThresholdHighSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SaturationThresholdHighSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationThresholdHighSliderValueChanged, true);
            app.SaturationThresholdHighSlider.Value = 1;
            app.LuminanceThresholdLowSliderLabel = uilabel(app.AnalysisSettingsTab);
            app.LuminanceThresholdLowSliderLabel.Text = 'Luminance Threshold Low';
            app.LuminanceThresholdLowSlider = uislider(app.AnalysisSettingsTab);
            app.LuminanceThresholdLowSlider.Limits = [0 1];
            app.LuminanceThresholdLowSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LuminanceThresholdLowSlider.ValueChangedFcn = createCallbackFcn(app, @LuminanceThresholdLowSliderValueChanged, true);
            app.LuminanceThresholdLowSlider.Value = 0.1;
            app.LuminanceThresholdHighSliderLabel = uilabel(app.AnalysisSettingsTab);
            app.LuminanceThresholdHighSliderLabel.Text = 'Luminance Threshold High';
            app.LuminanceThresholdHighSlider = uislider(app.AnalysisSettingsTab);
            app.LuminanceThresholdHighSlider.Limits = [0 1];
            app.LuminanceThresholdHighSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LuminanceThresholdHighSlider.ValueChangedFcn = createCallbackFcn(app, @LuminanceThresholdHighSliderValueChanged, true);
            app.LuminanceThresholdHighSlider.Value = 0.35;
            app.SetHueThresholdsLabel = uilabel(app.AnalysisSettingsTab);
            app.SetHueThresholdsLabel.Text = 'Set Hue Thresholds';
            app.SetSaturationThresholdsLabel = uilabel(app.AnalysisSettingsTab);
            app.SetSaturationThresholdsLabel.Text = 'Set Saturation Thresholds';
            app.SetLuminanceThresholdsLabel = uilabel(app.AnalysisSettingsTab);
            app.SetLuminanceThresholdsLabel.Text = 'Set Luminance Thresholds';
            app.ResetToDefaultHueValuesButton = uibutton(app.AnalysisSettingsTab, 'push');
            app.ResetToDefaultHueValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetToDefaultHueValuesButtonPushed, true);
            app.ResetToDefaultHueValuesButton.Text = 'Reset To Default Values';
            app.ResetToDefaultSaturationValues = uibutton(app.AnalysisSettingsTab, 'push');
            app.ResetToDefaultSaturationValues.ButtonPushedFcn = createCallbackFcn(app, @ResetToDefaultSaturationValuesButtonPushed, true);
            app.ResetToDefaultSaturationValues.Text = 'Reset To Default Values';
            app.ResetToDefaultLuminanceValues = uibutton(app.AnalysisSettingsTab, 'push');
            app.ResetToDefaultLuminanceValues.ButtonPushedFcn = createCallbackFcn(app, @ResetToDefaultLuminanceValuesButtonPushed, true);
            app.ResetToDefaultLuminanceValues.Text = 'Reset To Default Values';
            app.SetHueThresholdLowEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.SetHueThresholdLowEditFieldLabel.Text = 'Set Hue Threshold Low';
            app.SetHueThresholdLowEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.SetHueThresholdLowEditField.ValueChangedFcn = createCallbackFcn(app, @SetHueThresholdLowEditFieldValueChanged, true);
            app.SetHueThresholdLowEditField.Limits = [0 1];
            app.SetHueThresholdLowEditField.Value = 0.25;
            app.SetHueThresholdHighEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.SetHueThresholdHighEditFieldLabel.Text = 'Set Hue Threshold High';
            app.SetHueThresholdHighEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.SetHueThresholdHighEditField.ValueChangedFcn = createCallbackFcn(app, @SetHueThresholdHighEditFieldValueChanged, true);
            app.SetHueThresholdHighEditField.Limits = [0 1];
            app.SetHueThresholdHighEditField.Value = 0.45;
            app.SetSaturationThresholdLowEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.SetSaturationThresholdLowEditFieldLabel.Text = 'Set Saturation Threshold Low';
            app.SetSaturationThresholdLowEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.SetSaturationThresholdLowEditField.ValueChangedFcn = createCallbackFcn(app, @SetSaturationThresholdLowEditFieldValueChanged, true);
            app.SetSaturationThresholdLowEditField.Limits = [0 1];
            app.SetSaturationThresholdLowEditField.Value = 0.15;
            app.SetSaturationThresholdHighEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.SetSaturationThresholdHighEditFieldLabel.Text = 'Set Saturation Threshold High';
            app.SetSaturationThresholdHighEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.SetSaturationThresholdHighEditField.ValueChangedFcn = createCallbackFcn(app, @SetSaturationThresholdHighEditFieldValueChanged, true);
            app.SetSaturationThresholdHighEditField.Limits = [0 1];
            app.SetSaturationThresholdHighEditField.Value = 0.55;
            app.SetLuminanceThresholdLowEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.SetLuminanceThresholdLowEditFieldLabel.Text = 'Set Luminance Threshold Low';
            app.SetLuminanceThresholdLowEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.SetLuminanceThresholdLowEditField.ValueChangedFcn = createCallbackFcn(app, @SetLuminanceThresholdLowEditFieldValueChanged, true);
            app.SetLuminanceThresholdLowEditField.Limits = [0 1];
            app.SetLuminanceThresholdLowEditField.Value = 0.05;
            app.SetLuminanceThresholdHighEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.SetLuminanceThresholdHighEditFieldLabel.Text = 'Set Luminance Threshold High';
            app.SetLuminanceThresholdHighEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.SetLuminanceThresholdHighEditField.ValueChangedFcn = createCallbackFcn(app, @SetLuminanceThresholdHighEditFieldValueChanged, true);
            app.SetLuminanceThresholdHighEditField.Limits = [0 1];
            app.SetLuminanceThresholdHighEditField.Value = 0.35;
            app.BlobAnalysisSettingsLabel = uilabel(app.AnalysisSettingsTab);
            app.BlobAnalysisSettingsLabel.Text = 'Blob Analysis Settings';
            app.ResetToDefaultBlobAnalysisButton = uibutton(app.AnalysisSettingsTab, 'push');
            app.ResetToDefaultBlobAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetToDefaultBlobAnalysisButtonPushed, true);
            app.ResetToDefaultBlobAnalysisButton.Text = 'Reset To Default Values';
            app.BlobClumpingDistanceEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.BlobClumpingDistanceEditFieldLabel.Text = 'Blob Clumping Distance';
            app.BlobClumpingDistanceEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.BlobClumpingDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @BlobClumpingDistanceEditFieldValueChanged, true);
            app.BlobClumpingDistanceEditField.Limits = [0 Inf];
            app.BlobClumpingDistanceEditField.Value = 2.5;
            app.MaximumAreaToFillPixelsEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.MaximumAreaToFillPixelsEditFieldLabel.Text = 'Maximum Area To Fill (Pixels)';
            app.MaximumAreaToFillPixelsEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.MaximumAreaToFillPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumAreaToFillPixelsEditFieldValueChanged, true);
            app.MaximumAreaToFillPixelsEditField.Limits = [0 Inf];
            app.MaximumAreaToFillPixelsEditField.Value = 50;
            app.MinimumSizeForBlobDetectionPixelsEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.MinimumSizeForBlobDetectionPixelsEditFieldLabel.Text = 'Minimum Size For Blob Detection (Pixels)';
            app.MinimumSizeForBlobDetectionPixelsEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.MinimumSizeForBlobDetectionPixelsEditField.ValueChangedFcn = createCallbackFcn(app, @SetMinimumAreaForBlobsPixelsEditFieldValueChanged, true);
            app.MinimumSizeForBlobDetectionPixelsEditField.Limits = [0 Inf];
            app.MinimumSizeForBlobDetectionPixelsEditField.Value = 250;
            app.CameraHeight = uibuttongroup(app.AnalysisSettingsTab);
            app.CameraHeight.SelectionChangedFcn = createCallbackFcn(app, @CameraHeightSelectionChanged, true);
            app.Height1 = uitogglebutton(app.CameraHeight);
            app.Height1.Text = '54"';
            app.Height1.Value = true;
            app.Height2 = uitogglebutton(app.CameraHeight);
            app.Height2.Text = '48"';
            app.Height3 = uitogglebutton(app.CameraHeight);
            app.Height3.Text = '42"';
            app.Height4 = uitogglebutton(app.CameraHeight);
            app.Height4.Text = '36"';
            app.Height5 = uitogglebutton(app.CameraHeight);
            app.Height5.Text = '24"';
            app.Height6 = uitogglebutton(app.CameraHeight);
            app.Height6.Text = '18"';
            app.SelectCameraHeightLabel = uilabel(app.AnalysisSettingsTab);
            app.SelectCameraHeightLabel.Text = 'Select Camera Height';
            app.MinimumNIRValueEditFieldLabel = uilabel(app.AnalysisSettingsTab);
            app.MinimumNIRValueEditFieldLabel.Text = 'Minimum NIR Value';
            app.MinimumNIRValueEditField = uieditfield(app.AnalysisSettingsTab, 'numeric');
            app.MinimumNIRValueEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumNIRValueEditFieldValueChanged, true);
            app.MinimumNIRValueEditField.Limits = [0 1];
            app.MinimumNIRValueEditField.Value = 0.45;
            app.ImageCaptureTab = uitab(app.TabGroup);
            app.ImageCaptureTab.Title = 'Image Capture';
            app.ImageCaptureTab.Units = 'pixels';
            app.CaptureNewImageButton = uibutton(app.ImageCaptureTab, 'push');
            app.CaptureNewImageButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureNewImageButtonPushed, true);
            app.CaptureNewImageButton.Text = 'Capture New Image';
            app.LoadNewImageButton = uibutton(app.ImageCaptureTab, 'push');
            app.LoadNewImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewImageButtonPushed, true);
            app.LoadNewImageButton.Text = 'Load New Image';
            app.BlobExtractionButton = uibutton(app.ImageCaptureTab, 'push');
            app.BlobExtractionButton.ButtonPushedFcn = createCallbackFcn(app, @BlobExtractionButtonPushed, true);
            app.BlobExtractionButton.Text = 'Blob Extraction';
            app.PerformAnalysisButton = uibutton(app.ImageCaptureTab, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.MicaSenseBlue = uiaxes(app.ImageCaptureTab);
            title(app.MicaSenseBlue, 'Blue Band (475nm) Image');
            app.MicaSenseGreen = uiaxes(app.ImageCaptureTab);
            title(app.MicaSenseGreen, 'Green Band (560nm) Image');
            app.MicaSenseRed = uiaxes(app.ImageCaptureTab);
            title(app.MicaSenseRed, 'Red Band (668nm) Image');
            app.MicaSenseNearIR = uiaxes(app.ImageCaptureTab);
            title(app.MicaSenseNearIR, 'Near Infrared Band (840nm) Image');
            app.MicaSenseRedEdge = uiaxes(app.ImageCaptureTab);
            title(app.MicaSenseRedEdge, 'Red Edge Band (717nm) Image');
            app.MicaSenseRGB = uiaxes(app.ImageCaptureTab);
            title(app.MicaSenseRGB, 'Combined RGB Image');
            app.ProcessingImagesTab = uitab(app.TabGroup);
            app.ProcessingImagesTab.Title = 'Processing Images';
            app.ProcessingImagesTab.Units = 'pixels';
            app.Value = uiaxes(app.ProcessingImagesTab);
            title(app.Value, 'Luminance Image');
            app.Saturation = uiaxes(app.ProcessingImagesTab);
            title(app.Saturation, 'Saturation Image');
            app.Hue = uiaxes(app.ProcessingImagesTab);
            title(app.Hue, 'Hue Image');
            app.HueMask = uiaxes(app.ProcessingImagesTab);
            title(app.HueMask, 'Hue Mask');
            app.SaturationMask = uiaxes(app.ProcessingImagesTab);
            title(app.SaturationMask, 'Saturation Mask');
            app.ValueMask = uiaxes(app.ProcessingImagesTab);
            title(app.ValueMask, 'Luminance Mask');
            app.BinaryColored = uiaxes(app.ProcessingImagesTab);
            title(app.BinaryColored, 'Colored Binary Image');
            app.BlobExtractsTab = uitab(app.TabGroup);
            app.BlobExtractsTab.Title = 'Blob Extracts';
            app.BlobExtractsTab.Units = 'pixels';
            app.BlobExtract1Data = uilabel(app.BlobExtractsTab);
            app.BlobExtract1Data.Text = {'Red Value:'; ''; 'Green Value: '; ''; 'Blue Value: '; ''; 'IR Value:'; ''; 'NDVI Red Value:'; ''; 'NDVI Green Value:'; ''; 'NDVI Blue Value:'; ''; 'ENDVI Value:'; ''; 'Crop Area:'; ''};
            app.BlobImageExtract1 = uibutton(app.BlobExtractsTab, 'push');
            app.BlobImageExtract1.Enable = 'off';
            app.BlobImageExtract1.Icon = 'EmptyImage.png';
            app.BlobImageExtract1.Text = '';
            app.BlobImageExtract3 = uibutton(app.BlobExtractsTab, 'push');
            app.BlobImageExtract3.Enable = 'off';
            app.BlobImageExtract3.Text = '';
            app.BlobImageExtract2 = uibutton(app.BlobExtractsTab, 'push');
            app.BlobImageExtract2.Enable = 'off';
            app.BlobImageExtract2.Text = '';
            app.BlobImageExtract4 = uibutton(app.BlobExtractsTab, 'push');
            app.BlobImageExtract4.Enable = 'off';
            app.BlobImageExtract4.Text = '';
            app.BlobExtractLabel_2 = uilabel(app.BlobExtractsTab);
            app.BlobExtractLabel_2.Text = 'Blob Extract';
            app.BlobExtractLabel_3 = uilabel(app.BlobExtractsTab);
            app.BlobExtractLabel_3.Text = 'Blob Extract';
            app.BlobExtractLabel = uilabel(app.BlobExtractsTab);
            app.BlobExtractLabel.Text = 'Blob Extract';
            app.BlobExtractLabel_4 = uilabel(app.BlobExtractsTab);
            app.BlobExtractLabel_4.Text = 'Blob Extract';
            app.BlobExtract3DataTitle = uilabel(app.BlobExtractsTab);
            app.BlobExtract3DataTitle.Text = 'Blob Extract Data';
            app.BlobExtract4DataTitle = uilabel(app.BlobExtractsTab);
            app.BlobExtract4DataTitle.Text = 'Blob Extract Data';
            app.BlobExtract2DataTitle = uilabel(app.BlobExtractsTab);
            app.BlobExtract2DataTitle.Text = 'Blob Extract Data';
            app.BlobExtract1DataTitle = uilabel(app.BlobExtractsTab);
            app.BlobExtract1DataTitle.Text = 'Blob Extract Data';
            app.BlobExtract2Data = uilabel(app.BlobExtractsTab);
            app.BlobExtract2Data.Text = {'Red Value:'; ''; 'Green Value: '; ''; 'Blue Value: '; ''; 'IR Value:'; ''; 'NDVI Red Value:'; ''; 'NDVI Green Value:'; ''; 'NDVI Blue Value:'; ''; 'ENDVI Value:'; ''; 'Crop Area:'; ''};
            app.BlobExtract3Data = uilabel(app.BlobExtractsTab);
            app.BlobExtract3Data.Text = {'Red Value:'; ''; 'Green Value: '; ''; 'Blue Value: '; ''; 'IR Value:'; ''; 'NDVI Red Value:'; ''; 'NDVI Green Value:'; ''; 'NDVI Blue Value:'; ''; 'ENDVI Value:'; ''; 'Crop Area:'; ''};
            app.BlobExtract4Data = uilabel(app.BlobExtractsTab);
            app.BlobExtract4Data.Text = {'Red Value:'; ''; 'Green Value: '; ''; 'Blue Value: '; ''; 'IR Value:'; ''; 'NDVI Red Value:'; ''; 'NDVI Green Value:'; ''; 'NDVI Blue Value:'; ''; 'ENDVI Value:'; ''; 'Crop Area:'; ''};
            app.RemoveBlob1Button = uibutton(app.BlobExtractsTab, 'push');
            app.RemoveBlob1Button.ButtonPushedFcn = createCallbackFcn(app, @RemoveBlob1ButtonPushed, true);
            app.RemoveBlob1Button.Text = 'Remove Blob';
            app.RemoveBlob2Button = uibutton(app.BlobExtractsTab, 'push');
            app.RemoveBlob2Button.ButtonPushedFcn = createCallbackFcn(app, @RemoveBlob2ButtonPushed, true);
            app.RemoveBlob2Button.Text = 'Remove Blob';
            app.RemoveBlob3Button = uibutton(app.BlobExtractsTab, 'push');
            app.RemoveBlob3Button.ButtonPushedFcn = createCallbackFcn(app, @RemoveBlob3ButtonPushed, true);
            app.RemoveBlob3Button.Text = 'Remove Blob';
            app.RemoveBlob4Button = uibutton(app.BlobExtractsTab, 'push');
            app.RemoveBlob4Button.ButtonPushedFcn = createCallbackFcn(app, @RemoveBlob4ButtonPushed, true);
            app.RemoveBlob4Button.Text = 'Remove Blob';
            app.PreviousPageButton = uibutton(app.BlobExtractsTab, 'push');
            app.PreviousPageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousPageButtonPushed, true);
            app.PreviousPageButton.Text = 'Previous Page';
            app.NextPageButton = uibutton(app.BlobExtractsTab, 'push');
            app.NextPageButton.ButtonPushedFcn = createCallbackFcn(app, @NextPageButtonPushed, true);
            app.NextPageButton.Text = 'Next Page';
            app.SetCropIDEditField_5Label = uilabel(app.BlobExtractsTab);
            app.SetCropIDEditField_5Label.Text = 'Set Crop ID';
            app.SetCropIDEditField_1 = uieditfield(app.BlobExtractsTab, 'text');
            app.SetCropIDEditField_1.ValueChangedFcn = createCallbackFcn(app, @SetCropIDEditField_1ValueChanged, true);
            app.SetCropIDEditField_6Label = uilabel(app.BlobExtractsTab);
            app.SetCropIDEditField_6Label.Text = 'Set Crop ID';
            app.SetCropIDEditField_2 = uieditfield(app.BlobExtractsTab, 'text');
            app.SetCropIDEditField_3Label = uilabel(app.BlobExtractsTab);
            app.SetCropIDEditField_3Label.Text = 'Set Crop ID';
            app.SetCropIDEditField_3 = uieditfield(app.BlobExtractsTab, 'text');
            app.SetCropIDEditField_4Label = uilabel(app.BlobExtractsTab);
            app.SetCropIDEditField_4Label.Text = 'Set Crop ID';
            app.SetCropIDEditField_4 = uieditfield(app.BlobExtractsTab, 'text');
            app.CalculatedIndicesTab = uitab(app.TabGroup);
            app.CalculatedIndicesTab.Title = 'Calculated Indices';
            app.CalculatedIndicesTab.Units = 'pixels';
            app.SelectedImage = uiaxes(app.CalculatedIndicesTab);
            title(app.SelectedImage, 'NDVI False Color');
            app.SelectImageButtonGroup = uibuttongroup(app.CalculatedIndicesTab);
            app.SelectImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectImageButtonGroupSelectionChanged, true);
            app.SelectImageButtonGroup.Title = 'Select Image';
            app.NDVIFalseColorButton = uitogglebutton(app.SelectImageButtonGroup);
            app.NDVIFalseColorButton.Text = 'NDVI False Color';
            app.NDVIFalseColorButton.Value = true;
            app.NearInfraredButton = uitogglebutton(app.SelectImageButtonGroup);
            app.NearInfraredButton.Text = 'Near Infrared';
            app.CIRCompositeButton = uitogglebutton(app.SelectImageButtonGroup);
            app.CIRCompositeButton.Text = 'CIR Composite';
            app.NDVIRedButton = uitogglebutton(app.SelectImageButtonGroup);
            app.NDVIRedButton.Text = 'NDVI Red';
            app.NDVIGreenButton = uitogglebutton(app.SelectImageButtonGroup);
            app.NDVIGreenButton.Text = 'NDVI Green';
            app.NDVIBlueButton = uitogglebutton(app.SelectImageButtonGroup);
            app.NDVIBlueButton.Text = 'NDVI Blue';
            app.ENDVIButton = uitogglebutton(app.SelectImageButtonGroup);
            app.ENDVIButton.Text = 'ENDVI';
            app.DataViewTab = uitab(app.TabGroup);
            app.DataViewTab.Title = 'Data View';
            app.DataViewTab.Units = 'pixels';
            app.ExtractedData = uitable(app.DataViewTab);
            app.ExtractedData.ColumnName = {'Blob Number'; 'Mean Red'; 'Mean Green'; 'Mean Blue'; 'Mean NIR'; 'Mean NDVI Red'; 'Mean NDVI Green'; 'Mean NDVI Blue'; 'Mean ENDVI'; 'Approx. Area (cm²)'};
            app.ExtractedData.RowName = {};
            app.ExtractedCropDataLabel = uilabel(app.DataViewTab);
            app.ExtractedCropDataLabel.Text = 'Extracted Crop Data';
            app.ExportDataToXLSButton = uibutton(app.DataViewTab, 'push');
            app.ExportDataToXLSButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataToXLSButtonPushed, true);
            app.ExportDataToXLSButton.Text = 'Export Data To XLS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = KaciraLabCropAnalysis()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KaciraLabCropAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KaciraLabCropAnalysisUIFigure)
        end
    end
end